classdef DropDownPlus < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        DropDown_2  matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            if ~isempty(comp.EditItemsFcn)

                % Need to pass some metainformation, like what types
                [items, itemsData] = comp.EditItemsFcn(comp.Items, comp.ItemsData);
                comp.Items = items;
                comp.ItemsData = itemsData;
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, '001_-edit-128.png');
            comp.Button.Text = '';
            comp.DropDown_2 = uidropdown(comp.GridLayout);
        end
    end
end