classdef DropDownPlusPlus < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        DropDown    matlab.ui.control.DropDown
        Button      matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:) cell = {};
        Value
    end
    
    properties (Access = private)
        TypeSelectionContextMenu
    end
    
    properties (Hidden)
        AllowedTypes
        MetadataCollection
    end
    
    properties (Constant)
        Actions = ["<Select an instance>", "<Download instances>"]
    end

    properties
        SynchInstanceFcn
    end
    
    methods
        function set.Value(comp, value)
            comp.Value = value;
            comp.postSetValue()
        end

        function set.Items(comp, value)
            comp.Items = value;
            comp.postSetItems()
        end
               
        function set.ItemsData(comp, value)
            comp.ItemsData = value;
            comp.postSetItemsData()
        end
            
        function set.AllowedTypes(comp, value)
            comp.AllowedTypes = value;
            comp.postSetAllowedTypes()
        end


    end

    methods (Access = private)
        function postSetValue(comp)
            comp.DropDown.Value = comp.Value;
        end

        function postSetItems(comp)
            comp.DropDown.Items = comp.Items;
        end
                
        function postSetItemsData(comp)
            comp.DropDown.ItemsData = comp.ItemsData;
        end

        function postSetAllowedTypes(comp)
            comp.createContextMenu()
        end
    end

    methods
        function updateValue(comp, newValue, previousValue)
            comp.Value = newValue;

            evtData = matlab.ui.eventdata.ValueChangedData(...
                newValue, previousValue);

            notify(comp, 'ValueChanged', evtData);
        end
    end

    methods (Access = private)
        function changeType(comp, newType)
            comp.DropDown.Placeholder = sprintf("Create a new %s", newType);
        end

        function createContextMenu(comp, hFigure)

            if nargin < 2; hFigure = ancestor(comp, 'figure'); end

            if ~isempty(hFigure) && isvalid(hFigure)
                if isempty(comp.TypeSelectionContextMenu)
                    comp.TypeSelectionContextMenu = uicontextmenu(hFigure);
                end
                
                if ~isempty(comp.TypeSelectionContextMenu.Children)
                    delete(comp.TypeSelectionContextMenu.Children)
                end

                for i = 1:numel(comp.AllowedTypes)
                    iType = comp.AllowedTypes(i);
                    typeShortName = openminds.internal.utility.getSchemaShortName(iType);

                    typeMenuItem = uimenu(comp.TypeSelectionContextMenu);
                    typeMenuItem.Text = typeShortName;
                    typeMenuItem.Callback = @comp.onMetadataTypeContextMenuItemClicked;
                    if i == 1
                        typeMenuItem.Checked = 'on';
                    else
                        typeMenuItem.Checked = 'off';
                    end
                end
            end
        end

        function onMetadataTypeContextMenuItemClicked(comp, src, event)
            set(comp.TypeSelectionContextMenu.Children, 'Checked', 'off');
            event.Source.Checked = 'on';
            comp.changeType(event.Source.Text)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            hFigure = ancestor(comp, 'figure');
            comp.createContextMenu(hFigure)
        end

        % Button pushed function: Button
        function ButtonPushed(comp, event)
            pos = getpixelposition(event.Source, true);
            comp.TypeSelectionContextMenu.open(pos(1), pos(2))
        end

        % Value changed function: DropDown
        function DropDownValueChanged(comp, event)
            value = comp.DropDown.Value;
            comp.updateValue(value, event.PreviousValue)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 283 23];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            comp.Button.Icon = fullfile(pathToMLAPP, '001_-classification.png');
            comp.Button.Text = '';
            comp.DropDown = uidropdown(comp.GridLayout);
            comp.DropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @DropDownValueChanged, true);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end