classdef ArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        InteractiveListMenu  structeditor.component.InteractiveListMenu
        Panel                matlab.ui.container.Panel
        GridLayout2          matlab.ui.container.GridLayout
        ControlPanel         matlab.ui.container.Panel
        DropDown_2           matlab.ui.control.DropDown
        DropDown             matlab.ui.control.DropDown
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
    end

    properties (Access = private)
        ItemNodeList matlab.ui.container.TreeNode % Description

        UIControlContainers
    end
    
    methods (Access = private)
        
        function onSelectedItemChanged(app, src, evt)
            disp(evt.OldIndex)
            prevIdx = app.CurrentIdx;
            
            updatedData = app.UIControlContainers.Data;
            app.Data(prevIdx) = updatedData;

            data = app.Data(evt.NewIndex);
            app.UIControlContainers.Data = data;

            app.CurrentIdx = evt.NewIndex;
        end

        function onItemAdded(app, src, evt)
            updatedData = app.UIControlContainers.Data;
            app.Data(app.CurrentIdx) = updatedData;
            
            app.Data(end+1) = app.DefaultItem;
            app.CurrentIdx = numel(app.Data);
            
            app.UIControlContainers.Data = app.DefaultItem;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
            end

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;

            app.Data = data;
            app.DefaultItem = structeditor.utility.resetStructData(data(1));
            
            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data(i).(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data(i) );
                else
                    itemName = '';
                end
                
                app.InteractiveListMenu.addItem(itemName)
            end

            H = structeditor.UIControlContainer(app.ControlPanel, app.Data(1));
            app.UIControlContainers = H;

            app.CurrentIdx = 1;
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.Panel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.Panel);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'<Create New Item>', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = '<Create New Item>';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4'};
            app.DropDown_2.Value = 'Type 1';
            app.ControlPanel = uipanel(app.GridLayout2);
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end