classdef HeterogeneousArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        InteractiveListMenu      structeditor.component.InteractiveListMenu
        SelectmetadatatypeLabel  matlab.ui.control.Label
        ControlPanel             matlab.ui.container.Panel
        TypeDropDown             matlab.ui.control.DropDown
        ItemDropDown             matlab.ui.control.DropDown
        EditItemLabel            matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
        ItemTypes
        ItemLabels
    end

    properties (Access = private)
        ItemNodeList matlab.ui.container.TreeNode % Description

        UIControlContainers (1,:) structeditor.UIControlContainer
        ContainerTypeMap (1,:) string
        StructTypeMap (1,:) string

        TypeSelectorRadioButtons (1,:) matlab.ui.control.StateButton
    end

    % Todo:
    %   [ ] Work both with dropdown or radiobuttons for selecting type.
    %   [ ] Populate dropdown with existing items...


    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end

    methods
        function [data, names] = getData(app)

            data = app.Data;
            names = cell(1,numel(data));

            for i = 1:numel(data)

                name = app.InteractiveListMenu.getItemName(i);
    
                % Define the regular expression pattern
                pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
                
                % Get current name and annotation
                tokens = regexp(name, pattern, 'names');
                names{i} =  tokens.name;
            end
        end
    end
    
    methods (Access = private)
        
        function onSelectedItemChanged(app, src, evt)

            prevIdx = app.CurrentIdx;

            if prevIdx == evt.NewIndex
                disp('returning')
                return
            end

            previousType = app.StructTypeMap(prevIdx);
            newType = app.StructTypeMap(evt.NewIndex);

            prevContainerIdx = app.getContainerTypeIndex(previousType);
            newContainerIdx = app.getContainerTypeIndex(newType);
            
            updatedData = app.UIControlContainers(prevContainerIdx).Data;
            app.Data{prevIdx} = updatedData;

            data = app.Data{evt.NewIndex};
            app.UIControlContainers(newContainerIdx).Data = data;

            app.UIControlContainers(prevContainerIdx).Visible = 'off';
            app.UIControlContainers(newContainerIdx).Visible = 'on';

            app.CurrentIdx = evt.NewIndex;

            % Todo: Update selected type.
            selectedType = app.StructTypeMap(app.CurrentIdx);
            
            idx = find(strcmp({app.TypeSelectorRadioButtons.Tag}, selectedType));
            [app.TypeSelectorRadioButtons(:).Value] = deal(false);
            app.TypeSelectorRadioButtons(idx).Value = 1;
        end

        function onItemAdded(app, src, evt)

            % Get data for current form and update Data
            if ~isempty(app.UIControlContainers)
                previousType = app.StructTypeMap(app.CurrentIdx);
                prevContainerIdx = app.getContainerTypeIndex(previousType);
                updatedData = app.UIControlContainers(prevContainerIdx).Data;
                app.Data{app.CurrentIdx} = updatedData;
            end

            % Todo: get selected type...
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                %selectedType = app.TypeDropDown.Value;
                selectedType = app.getCurrentType();
                newStruct = app.DefaultItem.getType(selectedType);
            else
                selectedType = string(missing);
                newStruct = app.DefaultItem;
            end
            
            app.Data{end+1} = newStruct;
            app.CurrentIdx = numel(app.Data);
            app.StructTypeMap(app.CurrentIdx) = selectedType;
            
            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    H = structeditor.UIControlContainer(app.ControlPanel, newStruct);
                    app.UIControlContainers(end+1) = H;
                    app.ContainerTypeMap(end+1) = selectedType;
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end
            
            app.UIControlContainers(idx).Data = newStruct;
            
            % Add itemtype in parenthesis.
            typeName = app.getCurrentType("short");
            app.updateListItemName(app.CurrentIdx, typeName)
            app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;
        end
        
        function onTypeSelectorButtonPushed(app, src, evt)
            
            % Loop through all buttons to toggle them off except the clicked one
            for i = 1:length(app.TypeSelectorRadioButtons)
                if app.TypeSelectorRadioButtons(i) ~= src
                    app.TypeSelectorRadioButtons(i).Value = false;
                end
            end
            
            % Toggle the clicked button
            src.Value = true;

            app.changeSelectedType()
        end

        function changeContainerType(app)
            
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                %selectedType = app.TypeDropDown.Value;
                selectedType = app.getCurrentType();
                newStruct = app.DefaultItem.getType(selectedType);
            else
                selectedType = string(missing);
                newStruct = app.DefaultItem;
            end


            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    H = structeditor.UIControlContainer(app.ControlPanel, newStruct);
                    app.UIControlContainers(end+1) = H;
                    app.ContainerTypeMap(end+1) = selectedType;
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end

        end

        function idx = getContainerTypeIndex(app, typeName)
            idx = find( strcmp(app.ContainerTypeMap, typeName) );
        end

        function createTypeSelectorRadioButtons(app)

            availableTypes = app.DefaultItem.TypeNames;

            for i = 1:numel(availableTypes)
                splitNames = strsplit(availableTypes{i}, '.');
                availableTypes{i} = splitNames{end};
            end

            N = numel(availableTypes);

            % Create a grid layout with 1 row and N columns
            grid = uigridlayout(app.GridLayout, [1, N]);
            grid.Layout.Row = 4;
            grid.Layout.Column = 2;
            grid.Padding = 0;
        
            % Loop to create buttons in each cell
            for i = 1:N
                % Create a button and set its text to 'Button i'
                btn = uibutton(grid, "state", "Text", availableTypes{i});
                btn.Tag = app.DefaultItem.TypeNames(i);
                btn.ValueChangedFcn = @app.onTypeSelectorButtonPushed;

                % Set the button layout to the i-th column
                btn.Layout.Column = i;
                btn.Layout.Row = 1;

                app.TypeSelectorRadioButtons(i) = btn;
            end
            app.TypeSelectorRadioButtons(1).Value = true;
        end
    end

    methods
        function selectedType = getCurrentType(app, mode)
            
            if nargin < 2; mode = "full"; end

            if ~isempty( app.TypeSelectorRadioButtons )
                idx = find([app.TypeSelectorRadioButtons.Value]);
                selectedType = app.DefaultItem.TypeNames(idx);
            else
                selectedType = app.TypeDropDown.Value;
            end

            if mode == "short"
                selectedTypeSplit = strsplit(selectedType, '.');
                selectedType = selectedTypeSplit{end};
            end
        end

        function changeSelectedType(app)

            if app.CurrentIdx >= 1

                currentType = app.StructTypeMap(app.CurrentIdx);

                % Check if form is dirty and open dialog.
                idx = app.getContainerTypeIndex(currentType);
                if not( app.UIControlContainers(idx).IsClean() )
                    msg = "Changing type will discard current changes.";
                    title = "Confirm change";
                    selection = uiconfirm(app.UIFigure, msg, title, ...
                        "Options",["Update Type","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                    if selection == "Cancel"
                        return
                    end
                end
                
                % Get instance type and default data
                if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                    %selectedType = app.TypeDropDown.Value;
                    selectedType = app.getCurrentType();
                    newStruct = app.DefaultItem.getType(selectedType);
                else
                    selectedType = string(missing);
                    newStruct = app.DefaultItem;
                end

                typeName = app.getCurrentType("short");
                app.updateListItemName(app.CurrentIdx, typeName)
            
                % Update instance data
                app.Data{app.CurrentIdx} = newStruct;

                % Update app.StructTypeMap
                app.StructTypeMap(app.CurrentIdx) = selectedType;

                app.changeContainerType()
                idx = app.getContainerTypeIndex(selectedType);
                app.UIControlContainers(idx).Data = newStruct;
            else
                % Do nothing.
            end
        end

        function updateListItemName(app, listItemIndex, typeName)
            
            name = app.InteractiveListMenu.getItemName(listItemIndex);

            % Define the regular expression pattern
            pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
            
            % Get current name and annotation
            tokens = regexp(name, pattern, 'names');

            name  = sprintf('%s (%s)', tokens.name, typeName);
            app.InteractiveListMenu.renameItem(listItemIndex, name)
        end
    end

    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.DefaultItem = {}
            end

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;

            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                if numel(app.DefaultItem.TypeNames) < 4
                    app.createTypeSelectorRadioButtons()
                    app.TypeDropDown.Visible = 'off';
                else
                    app.TypeDropDown.Items = app.DefaultItem.TypeNames;
                end
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data{i}.(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data{i} );
                else
                    itemName = '';
                end
                
                app.InteractiveListMenu.addItem(itemName)
                selectedType = app.DefaultItem.getStructType(app.Data{i});
                app.StructTypeMap(i) = selectedType;
            end

            if ~isempty(  app.Data )

                H = structeditor.UIControlContainer(app.ControlPanel, app.Data{1});
                app.UIControlContainers = H;

                app.CurrentIdx = 1;
                selectedType = app.DefaultItem.getStructType(app.Data{1});
                app.ContainerTypeMap(1) = selectedType;
            else
                app.CurrentIdx = 0;
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EditItemLabel = uilabel(app.GridLayout);
            app.EditItemLabel.Text = 'Edit Item';
            app.ItemDropDown = uidropdown(app.GridLayout);
            app.ItemDropDown.Items = {'<Create New Item>', 'Option 1', 'Option 2', 'Option 3', 'Option 4'};
            app.ItemDropDown.Value = '<Create New Item>';
            app.TypeDropDown = uidropdown(app.GridLayout);
            app.TypeDropDown.Items = {'Type 1', 'Type 2', 'Type 3', 'Type 4'};
            app.TypeDropDown.Value = 'Type 1';
            app.ControlPanel = uipanel(app.GridLayout);
            app.SelectmetadatatypeLabel = uilabel(app.GridLayout);
            app.SelectmetadatatypeLabel.Text = 'Select metadata type';
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeterogeneousArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end